classdef HolesFilling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        btnLoadImage  matlab.ui.control.Button
        axesImage     matlab.ui.control.UIAxes
    end

    properties (Access = public)
        
    end
    
    properties (Access = private)
        imgMatrix % Description
        imgLoc % Description
    end
    
    methods (Access = private)
          
         
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnLoadImage
        function btnLoadImagePushed(app, event)
            % Get the pathname and filename
            [File_Name, Path_Name] = uigetfile('PATHNAME');
            
            % Show the image in the GUI axes
            app.imgMatrix = imread([Path_Name,File_Name]);           
            imshow(app.imgMatrix, 'Parent', app.axesImage);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            [row, col] = size(app.imgMatrix);
            
            for i = 1:row
               for j = 1: col
                   if app.imgMatrix(i,j) > 1
                        app.imgMatrix = imbinarize(app.imgMatrix);
                        continue;
                   end
               end
            end

            Icomp = imcomplement(app.imgMatrix);
            
            temp = app.axesImage.CurrentPoint; 
            app.imgLoc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates  
            
            m = int16(app.imgLoc(1));
            n = int16(app.imgLoc(2));
            fprintf('X = %d \n',n);
            fprintf('Y = %d \n',m);
            
            if app.imgMatrix(n,m) == 0
               %Generate a X0 array
               X0 = zeros(row,col);
               X0(n,m) = 1; 
               %Generate structuring element B
               B = [0 1 0; 1 1 1; 0 1 0];
               
               SE = strel(B);
               
               X1 = bitand(imdilate(X0,SE), Icomp);
               
               while ~isequal(X0,X1) 
                X0 = X1;
                X1 = bitand(imdilate(X0,SE), Icomp);
                app.imgMatrix = bitor(X1, app.imgMatrix);
               end
               
               imshow(app.imgMatrix, 'Parent', app.axesImage);
            else 
               app.imgMatrix = bitand(app.imgMatrix, Icomp); 
               
               imshow(app.imgMatrix, 'Parent', app.axesImage);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.btnLoadImage = uibutton(app.UIFigure, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImagePushed, true);
            app.btnLoadImage.Text = 'Load Image';
            app.axesImage = uiaxes(app.UIFigure);
            title(app.axesImage, '')
            xlabel(app.axesImage, '')
            ylabel(app.axesImage, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HolesFilling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end